classdef ProjectionApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScaffoldUIFigure             matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        InfoMenu                     matlab.ui.container.Menu
        SessionDetailsMenu           matlab.ui.container.Menu
        SliceSliderLabel             matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        DataLoadedLampLabel          matlab.ui.control.Label
        DataLoadedLamp               matlab.ui.control.Lamp
        UIFilter                     matlab.ui.control.UIAxes
        NoiseFilterCheckBox          matlab.ui.control.CheckBox
        FillHolesCheckBox            matlab.ui.control.CheckBox
        ApplyFiltersButton           matlab.ui.control.Button
        BinaryThresholdingCheckBox   matlab.ui.control.CheckBox
        ConnectedComponentsCheckBox  matlab.ui.control.CheckBox
        UIBoneProjection             matlab.ui.control.UIAxes
        OptimizationiterationsEditFieldLabel  matlab.ui.control.Label
        OptimizationiterationsEditField  matlab.ui.control.NumericEditField
        LowerBoundforangleEditFieldLabel  matlab.ui.control.Label
        LowerBoundforangleEditField  matlab.ui.control.NumericEditField
        UpperBoundforangleEditFieldLabel  matlab.ui.control.Label
        UpperBoundforangleEditField  matlab.ui.control.NumericEditField
        RunOptimizerButton           matlab.ui.control.Button
        ExportBoneSTLButton          matlab.ui.control.Button
        ExportScaffoldSTLButton      matlab.ui.control.Button
        ShowOutputFolderButton       matlab.ui.control.Button
        SelectInputFolderButton      matlab.ui.control.Button
        UIRaw                        matlab.ui.control.UIAxes
        UIHoleProjection             matlab.ui.control.UIAxes
        DisplayOptimizationStatsCheckBox  matlab.ui.control.CheckBox
        ThicknessEstimationCriteriaButtonGroup  matlab.ui.container.ButtonGroup
        UseFullBoneVolumeButton      matlab.ui.control.RadioButton
        UseLocalFractureSiteButton   matlab.ui.control.RadioButton
        StructingElement1SizeErosionLabel  matlab.ui.control.Label
        StructingElement1SizeErosionEditField  matlab.ui.control.NumericEditField
        StructingElement2SizeDilationLabel  matlab.ui.control.Label
        StructingElement2SizeDilationEditField  matlab.ui.control.NumericEditField
        GetThicknessEstimateButton   matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: SelectInputFolderButton
        function SelectInputFolderButtonPushed(app, event)
            
            Input_directory_path = uigetdir(pwd,'Select folder containing CT-Scan Slices');
            Input_directory = dir(strcat(Input_directory_path, '\*.TIF'));
            % disp (['Selected directory is: ', Input_directory_path]);
            
            %    disp(['Input Directory has ', num2str(length(Input_directory)), ' Slices']);
            
            %%% Initialize the array using zeros based on the size of the images.
            
            %%% Find a way to put the values below in th program dynamically.(todo)
            if(isempty(Input_directory))
                uialert(app.ScaffoldUIFigure,'No TIFF Images Found in specified folder','Error');
                return;
            end
            
            global image_array;
            
            FileName=Input_directory(1).name;
            temp =imread(strcat(relativepath(Input_directory_path), FileName));
            image_array = zeros(size(temp,1),size(temp,2), length(Input_directory));
            
            disp('Creating slices volume... ');
            
            
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Importing and creating slices volume... ');
            app.ScaffoldUIFigure.Visible = 'off';
            app.ScaffoldUIFigure.Visible = 'on';
            for i=1:length(Input_directory)
                d.Value = i/length(Input_directory);
                FileNames=Input_directory(i).name;
                temp=imread(strcat(relativepath(Input_directory_path), FileNames));
                temp=temp(:,:,1);
                image_array(:,:,i)=temp;
            end
            d.close();
            disp('Slices volume created successfully!');
            
            
            global RawWindow;
            
            prompt = {'Please specify downscaling factor of volume (+ve Integer). Enter 1 if full resolution needed: '};
            dlgtitle = 'Input';
            dims = [1 55];
            definput = {num2str(5)};
            downscaling_factor = inputdlg(prompt,dlgtitle,dims,definput);
            
            if(isempty(downscaling_factor))
                uialert(app.ScaffoldUIFigure,'Please provide a downscaling value','No Input Provided!','Icon','warning');
                return;
            end
            
            
            global image_array_downsized;
            
            image_array_downsized =image_array(1:str2double(downscaling_factor):end, 1:str2double(downscaling_factor):end, 1:str2double(downscaling_factor):end);
            
            
            RawWindow = imshow(image_array_downsized(:,:,1),[],'Parent',app.UIRaw);
            app.DataLoadedLamp.Color = 'green';
            
            app.SliceSlider.Limits = [1 size(image_array_downsized,3)];
            app.SliceSlider.Enable = true;
            
            global FilterFlag;
            FilterFlag =0;
            
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            global SliceSliderValue;
            SliceSliderValue = event.Value;
            global image_array_downsized;
            global RawWindow;
            global FilterWindow;
            global Filtered_image_array;
            global FilterFlag;
            
            set(RawWindow, 'CData', image_array_downsized(:,:,int64(SliceSliderValue)));
            if(FilterFlag ==1)
                set(FilterWindow, 'CData', Filtered_image_array(:,:,int64(SliceSliderValue)));
            end
            disp(int64(SliceSliderValue));
        end

        % Button pushed function: ApplyFiltersButton
        function ApplyFiltersButtonPushed(app, event)
            global image_array_downsized;
            global FilterWindow;
            global Filtered_image_array;
            global full_image_array_filtered;
            global image_array;
            global SliceSliderValue;
            global FilterFlag;
            
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Applying Selected Filters... ','Indeterminate','on');
            
            Filtered_image_array = image_array_downsized;
            if(app.NoiseFilterCheckBox.Value == 1)
                %%% Remove Noise (todo)
                %%% Bilateral Filtering? (todo)
            end
            
            if(app.FillHolesCheckBox.Value ==1)
                %%% Fill Holes (todo)
            end
            
            if(app.BinaryThresholdingCheckBox.Value ==1)
                %%% Applying binary thresholding
                disp('Applying Binary Thresholding... ');
                
                level = 255*graythresh(image_array_downsized(:)./255);
                
                image_array_downsized_binarized= double(imbinarize(image_array_downsized,level));
                Filtered_image_array = image_array_downsized_binarized;
                
                level = 255*graythresh(image_array(:)./255);
                
                image_array_binarized= double(imbinarize(image_array,level));
                full_image_array_filtered = image_array_binarized;
                
            end
            
            if(app.ConnectedComponentsCheckBox.Value ==1)
                %%% Finding largest connected components and storing it in the array
                disp('Finding Lagest Connected Component... ');
                
                Connected_Components = bwconncomp(Filtered_image_array);
                largestCompIndex = 0;
                tempLen=0;
                
                for i =1:Connected_Components.NumObjects
                    temp = length(Connected_Components.PixelIdxList{1,i});
                    if temp >tempLen
                        tempLen = temp;
                        largestCompIndex = i;
                    end
                end
                
                %%% Using the connected component indices, make the pixels in that much
                %%% selection as 1. This will filter out all the disconnected pixels
                
                %%% Display Maximum connected component details (todo)
                image_array_downsized_binarized_masked = zeros(size(Filtered_image_array));
                image_array_downsized_binarized_masked (Connected_Components.PixelIdxList{1,largestCompIndex}) = 1;
                Filtered_image_array = image_array_downsized_binarized_masked;
            end
            d.close();
            FilterWindow = imshow(Filtered_image_array(:,:,int64(SliceSliderValue)),[],'Parent',app.UIFilter);
            disp('Bone volume pre-processed successfully!');
            FilterFlag =1;
            %%% We saved the above array here in previous script, make it work without it(todo)
            
        end

        % Button pushed function: RunOptimizerButton
        function RunOptimizerButtonPushed(app, event)
            global image_array;
            global BoneProjection;
            global HoleProjection;
            global imgDataDBROT3;
            global Filtered_image_array;
            global full_image_array_filtered;
            global FilterFlag;
            global imgDataDBROT3_Full;
            
            if(FilterFlag==0)
                uialert(app.ScaffoldUIFigure,'Please Apply Filters before proceeding with optimization...','Filters not applied!','Icon','warning');
                return;
            end
            % fig = figure('Name','Preview of Optimization','NumberTitle','off');
            
            if(app.DisplayOptimizationStatsCheckBox.Value ==1)
                
                options=optimoptions('simulannealbnd','MaxIterations', app.OptimizationiterationsEditField.Value,'PlotFcns',...
                    {@saplotbestx,@saplotbestf,@saplotx,@saplotf});
            else
                
                options=optimoptions('simulannealbnd','MaxIterations', app.OptimizationiterationsEditField.Value);
            end
            
            
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Performing Rotation Optimization... ','Indeterminate','on');
            
            [rotationresult,functionvalue] =simulannealbnd(@optimizeRotationMaxArea,(10*rand(1,3)),app.LowerBoundforangleEditField.Value,app.UpperBoundforangleEditField.Value, options);
            
            
            close all
            disp(['Optimization completed successfully! The optimal angles are (ZYX): ',num2str(rotationresult), ' with an area of ', num2str(abs(functionvalue)) , ' units']);
            d.close();
            
            %%% Plug in the rotationresult values into the rotation values below (todo)
            
            %%% Change the filtered_image_array to image_array later when everything is working. Right now it runs into out of memory problem(todo)
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Generating Previews... ','Indeterminate','on');
            
            imgDataDBROT1 = double(imrotate3(Filtered_image_array,int8(rotationresult(1)),[0 1 0]));
            imgDataDBROT3 = double(imrotate3(imgDataDBROT1 ,int8(rotationresult(2)),[1 0 0]));
            %imgDataDBROT3 = double(imrotate3(imgDataDBROT2 ,int8(rotationresult(3)),[1 0 0]));
            
            
            sum_projection=mean(imgDataDBROT3,3);
            
            binary_sum_projection = (sum_projection>0);
            
            filled_sum_projection = imfill(binary_sum_projection,'holes');
            
            
            binary_hole_projection =double(abs(filled_sum_projection-binary_sum_projection));


            BoneProjection = imshow(sum_projection,[],'Parent',app.UIBoneProjection);
            HoleProjection = imshow(binary_hole_projection,[],'Parent',app.UIHoleProjection);
            
            d.close();
            
            
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Applying rotations to Full Volume... ','Indeterminate','on');
            
            
            imgDataDBROT1_Full = double(imrotate3(full_image_array_filtered,int8(rotationresult(1)),[0 1 0]));
            imgDataDBROT3_Full = double(imrotate3(imgDataDBROT1 ,int8(rotationresult(2)),[1 0 0]));
            
            %%%Only doing rotation along X and Y axis for this dataset
            
            d.close();


            disp('Rotation completed!');


        end

        % Button pushed function: ExportBoneSTLButton
        function ExportBoneSTLButtonPushed(app, event)
            %global imgDataDBROT3;
            global imgDataDBROT3_Full;
            
            
            if(exist('STL_Outputs\','dir'))
                %  disp('WARNING: Output folder exists. New outputs will overwrite old data!');
                %                 alert = uialert(app.ScaffoldUIFigure,'WARNING: Output folder exists. New outputs will overwrite old data!','Output Folder Found!','Icon','warning');
            else
                %disp('No output folder found, creating folder now... ');
                mkdir('STL_Outputs');
                %  disp('Output folder created!');
                %                 alert = uialert(app.ScaffoldUIFigure,'Output Folder Does not exist! Creating folder now...','No Output Folder Found!','Icon','warning');
                
            end
            
            
            %             waitfor(alert);


            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Writing bone volume to disk... ','Indeterminate','on');
            
            
            disp('Writing bone volume to disk... ');
            ind = find(imgDataDBROT3_Full);
            [x, y, z] = ind2sub(size(imgDataDBROT3_Full), ind);
            P = [x y z];
            shp = alphaShape(P);
            [bf,P] = boundaryFacets(shp);
            
            stlwrite('STL_Outputs\bone_volume.stl',bf,P);
            d.close();
            % disp('Bone volume STL file written to disk! Find it in STL_Outputs Folder!');
            uialert(app.ScaffoldUIFigure,'Bone volume STL file written to disk! Find it in STL_Outputs Folder!','Bone STL Created!','Icon','success');
            
            
        end

        % Button pushed function: ExportScaffoldSTLButton
        function ExportScaffoldSTLButtonPushed(app, event)
            global imgDataDBROT3;
            global thickness_estimate;
            global imgDataDBROT3_Full;
            
            
            if(exist('STL_Outputs\','dir'))
                %  disp('WARNING: Output folder exists. New outputs will overwrite old data!');
                
            else
                %disp('No output folder found, creating folder now... ');
                mkdir('STL_Outputs');
                %  disp('Output folder created!');
                
            end


            %%% Consider below variable as 'A'
            sum_projection=mean(imgDataDBROT3_Full,3);
            
            %%% Note: we create binarized version of sum_projection for hole
            %%% calculation, and the original sum version is used for thickness
            %%% estimation.
            binary_sum_projection = (sum_projection>0);
            
            %%% Consider below variable as 'B'
            filled_sum_projection = imfill(binary_sum_projection,'holes');
            
            
            %%% B is a superset of A, which contains A as well as pixels of the hole
            %%% region. Now we can obtain the hole region pixels by subtraction (B-A)
            
            binary_hole_projection =abs(filled_sum_projection-binary_sum_projection);
            
            %%% This projection is the final shape of the hole that we can use to
            %%% create the final volume for printing. Now we need to replicate this
            %%% shape for <thickness> times along Z-Axis to obtain required depth.
            
            
            %%% Step 5: Calculating Thickness of the volume
            
            %%% Thickness of the bone can be considered as the sum of all the voxels
            %%% along the depth of the bone, when each voxel is a unit value of 1.
            
            
            %%% Put the notes here (todo)
            struct_elem1 = strel('square',5);
            binary_hole_eroded=imopen(binary_hole_projection, struct_elem1);
            
            struct_elem2 = strel('square', 31);
            binary_hole_dilated = imdilate(binary_hole_eroded,struct_elem2, 'same');
            
            binIntersection = binary_hole_dilated&binary_sum_projection;
            
         %   thickness_estimate= round(100* mean(nonzeros(double(binIntersection).*sum_projection)));


            prompt = {'Please verify thickness of scaffold. Below value is automatically calculated : '};
            dlgtitle = 'Input';
            dims = [1 55];
            definput = {num2str(thickness_estimate)};
            final_thickness = inputdlg(prompt,dlgtitle,dims,definput);
            
            final_hole_volume=zeros(size(imgDataDBROT3_Full,1), size(imgDataDBROT3_Full, 2), round(ceil(str2double(final_thickness))));
            
            for i=1:round(str2double(final_thickness))
                final_hole_volume(:,:,i)=imfill(binary_hole_eroded,'holes');
            end
            
            disp('Scaffold volume created!');
            
            disp('Creating isosurface of scaffold... ');
            
            
            isosurface_of_hole_caps = isocaps(final_hole_volume,.5);
            isosurface_of_hole_wall = isosurface(final_hole_volume,.5);
            
            isosurface_of_hole_volume = isosurface_of_hole_wall;
            isosurface_of_hole_volume.vertices = [isosurface_of_hole_volume.vertices; isosurface_of_hole_caps.vertices];
            isosurface_of_hole_volume.faces = [isosurface_of_hole_volume.faces; ...
                isosurface_of_hole_caps.faces + length(isosurface_of_hole_wall.vertices(:,1))];
            
            
            % isosurface_of_hole_volume = isosurface(final_hole_volume,.5);
            %patchVolume = patch(isosurf);
            %patchFilled = convhulln(isosurf)
            
            %%% Test if patch conversion is needed or not (todo)
            disp('Isosurface created successfully!');
            
            disp('Writing scaffold volume to disk... ');
            
            
            d = uiprogressdlg(app.ScaffoldUIFigure,'Title','Please Wait',...
                'Message','Writing scaffold volume to disk... ','Indeterminate','on');
            
            
            stlwrite('STL_Outputs\scaffold_volume.stl',isosurface_of_hole_volume);
            d.close();
            
            %  disp('Scaffold STL written to disk! Find it in STL_Outputs Folder!');
            uialert(app.ScaffoldUIFigure,'Scaffold STL written to disk! Find it in STL_Outputs Folder!','Scaffold STL Created!','Icon','success');
            
        end

        % Button pushed function: ShowOutputFolderButton
        function ShowOutputFolderButtonPushed(app, event)
            if(exist('STL_Outputs\','dir'))
                winopen('STL_Outputs\');
            else
                uialert(app.ScaffoldUIFigure,'Output Folder Does not exist! Please run the program once!','No Output Folder Found!');
                
                
            end
        end

        % Button pushed function: GetThicknessEstimateButton
        function GetThicknessEstimateButtonPushed(app, event)
            global thickness_estimate;
            global imgDataDBROT3_Full;
            global imgDataDBROT3;
            sum_projection=sum(imgDataDBROT3_Full,3);
            disp(size(imgDataDBROT3_Full))
            %figure, imshow(imgDataDBROT3_Full(:,:,150),[])
            if(app.UseLocalFractureSiteButton.Value == true)
                
                
                binary_sum_projection = (sum_projection>0);
                
                filled_sum_projection = imfill(binary_sum_projection,'holes');
                
                binary_hole_projection =abs(filled_sum_projection-binary_sum_projection);
                
                struct_elem1 = strel('square',app.StructingElement1SizeErosionEditField.Value);
                binary_hole_eroded=imopen(binary_hole_projection, struct_elem1);
                
                struct_elem2 = strel('square', app.StructingElement2SizeDilationEditField.Value);
                binary_hole_dilated = imdilate(binary_hole_eroded,struct_elem2, 'same');
                
                binIntersection = binary_hole_dilated&binary_sum_projection;
                
                thickness_estimate= round(mean(nonzeros(double(binIntersection).*sum_projection)));
                
            else
                thickness_estimate= round(mean(nonzeros(sum_projection)));
                
            end
            uialert(app.ScaffoldUIFigure,['Estimated Thickness is: ', num2str(thickness_estimate)],'Thickness Estimation Complete!','Icon','success');
            
        end

        % Selection changed function: 
        % ThicknessEstimationCriteriaButtonGroup
        function ThicknessEstimationCriteriaButtonGroupSelectionChanged(app, event)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScaffoldUIFigure = uifigure;
            app.ScaffoldUIFigure.Name = 'Scaffold';
            app.FileMenu = uimenu(app.ScaffoldUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.InfoMenu = uimenu(app.ScaffoldUIFigure);
            app.InfoMenu.Text = 'Info';
            app.SessionDetailsMenu = uimenu(app.InfoMenu);
            app.SessionDetailsMenu.Text = 'Session Details';
            app.SliceSliderLabel = uilabel(app.ScaffoldUIFigure);
            app.SliceSliderLabel.Text = 'Slice#';
            app.SliceSlider = uislider(app.ScaffoldUIFigure);
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Enable = 'off';
            app.SliceSlider.Value = 1;
            app.DataLoadedLampLabel = uilabel(app.ScaffoldUIFigure);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.ScaffoldUIFigure);
            app.UIFilter = uiaxes(app.ScaffoldUIFigure);
            title(app.UIFilter, 'After Filtering')
            app.NoiseFilterCheckBox = uicheckbox(app.ScaffoldUIFigure);
            app.NoiseFilterCheckBox.Text = 'Noise Filter';
            app.FillHolesCheckBox = uicheckbox(app.ScaffoldUIFigure);
            app.FillHolesCheckBox.Text = 'Fill Holes';
            app.ApplyFiltersButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFiltersButtonPushed, true);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.BinaryThresholdingCheckBox = uicheckbox(app.ScaffoldUIFigure);
            app.BinaryThresholdingCheckBox.Text = 'Binary Thresholding';
            app.ConnectedComponentsCheckBox = uicheckbox(app.ScaffoldUIFigure);
            app.ConnectedComponentsCheckBox.Text = 'Connected Components';
            app.UIBoneProjection = uiaxes(app.ScaffoldUIFigure);
            title(app.UIBoneProjection, 'Projection Of Bone')
            app.OptimizationiterationsEditFieldLabel = uilabel(app.ScaffoldUIFigure);
            app.OptimizationiterationsEditFieldLabel.Text = 'Optimization iterations';
            app.OptimizationiterationsEditField = uieditfield(app.ScaffoldUIFigure, 'numeric');
            app.OptimizationiterationsEditField.Value = 10;
            app.LowerBoundforangleEditFieldLabel = uilabel(app.ScaffoldUIFigure);
            app.LowerBoundforangleEditFieldLabel.Text = 'Lower Bound for angle';
            app.LowerBoundforangleEditField = uieditfield(app.ScaffoldUIFigure, 'numeric');
            app.LowerBoundforangleEditField.Value = -20;
            app.UpperBoundforangleEditFieldLabel = uilabel(app.ScaffoldUIFigure);
            app.UpperBoundforangleEditFieldLabel.Text = 'Upper Bound for angle';
            app.UpperBoundforangleEditField = uieditfield(app.ScaffoldUIFigure, 'numeric');
            app.UpperBoundforangleEditField.Value = 20;
            app.RunOptimizerButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.RunOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizerButtonPushed, true);
            app.RunOptimizerButton.Text = 'Run Optimizer';
            app.ExportBoneSTLButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.ExportBoneSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBoneSTLButtonPushed, true);
            app.ExportBoneSTLButton.Text = 'Export Bone STL';
            app.ExportScaffoldSTLButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.ExportScaffoldSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ExportScaffoldSTLButtonPushed, true);
            app.ExportScaffoldSTLButton.Text = 'Export Scaffold STL';
            app.ShowOutputFolderButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.ShowOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOutputFolderButtonPushed, true);
            app.ShowOutputFolderButton.Text = 'Show Output Folder';
            app.SelectInputFolderButton = uibutton(app.ScaffoldUIFigure, 'push');
            app.SelectInputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFolderButtonPushed, true);
            app.SelectInputFolderButton.Text = 'Select Input Folder';
            app.UIRaw = uiaxes(app.ScaffoldUIFigure);
            title(app.UIRaw, 'CT Images')
            app.UIHoleProjection = uiaxes(app.ScaffoldUIFigure);
            title(app.UIHoleProjection, 'Hole Projection (Scaffold)')
            app.DisplayOptimizationStatsCheckBox = uicheckbox(app.ScaffoldUIFigure);
            app.DisplayOptimizationStatsCheckBox.Text = 'Display Optimization Stats';
            app.ThicknessEstimationCriteriaButtonGroup = uibuttongroup(app.ScaffoldUIFigure);
            app.ThicknessEstimationCriteriaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThicknessEstimationCriteriaButtonGroupSelectionChanged, true);
            app.ThicknessEstimationCriteriaButtonGroup.Title = 'Thickness Estimation Criteria';
            app.UseFullBoneVolumeButton = uiradiobutton(app.ThicknessEstimationCriteriaButtonGroup);
            app.UseFullBoneVolumeButton.Text = 'Use Full Bone Volume';
            app.UseFullBoneVolumeButton.Value = true;
            app.UseLocalFractureSiteButton = uiradiobutton(app.ThicknessEstimationCriteriaButtonGroup);
            app.UseLocalFractureSiteButton.Text = 'Use Local Fracture Site';
            app.StructingElement1SizeErosionLabel = uilabel(app.ThicknessEstimationCriteriaButtonGroup);
            app.StructingElement1SizeErosionLabel.Text = 'Structing Element 1 Size (Erosion)';
            app.StructingElement1SizeErosionEditField = uieditfield(app.ThicknessEstimationCriteriaButtonGroup, 'numeric');
            app.StructingElement1SizeErosionEditField.Value = 5;
            app.StructingElement2SizeDilationLabel = uilabel(app.ThicknessEstimationCriteriaButtonGroup);
            app.StructingElement2SizeDilationLabel.Text = 'Structing Element 2 Size (Dilation)';
            app.StructingElement2SizeDilationEditField = uieditfield(app.ThicknessEstimationCriteriaButtonGroup, 'numeric');
            app.StructingElement2SizeDilationEditField.Value = 31;
            app.GetThicknessEstimateButton = uibutton(app.ThicknessEstimationCriteriaButtonGroup, 'push');
            app.GetThicknessEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @GetThicknessEstimateButtonPushed, true);
            app.GetThicknessEstimateButton.Text = 'Get Thickness Estimate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectionApplication

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScaffoldUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScaffoldUIFigure)
        end
    end
end